classdef CD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        psi                          matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        theta                        matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        phi                          matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Z                            matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        Y                            matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        X                            matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        PosicindelefectorfinalLabel  matlab.ui.control.Label
        CinemticadirectaLabel        matlab.ui.control.Label
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        J6Slider                     matlab.ui.control.Slider
        J6SliderLabel                matlab.ui.control.Label
        J5Slider                     matlab.ui.control.Slider
        J5SliderLabel                matlab.ui.control.Label
        J4Slider                     matlab.ui.control.Slider
        J4SliderLabel                matlab.ui.control.Label
        J3Slider                     matlab.ui.control.Slider
        J3SliderLabel                matlab.ui.control.Label
        J2Slider                     matlab.ui.control.Slider
        J2SliderLabel                matlab.ui.control.Label
        J1Slider                     matlab.ui.control.Slider
        J1SliderLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Robot;
    end
    
    methods (Access = public)
        
        function draw(app)
            tht1=app.J1Slider.Value;
            tht2=app.J2Slider.Value;
            tht3=app.J3Slider.Value;
            tht4=app.J4Slider.Value;
            tht5=app.J5Slider.Value;
            tht6=app.J6Slider.Value;
            q=[tht1,tht2,tht3,tht4,tht5,tht6]
            app.UIAxes.Visible='on';
            app.Robot.plot(q)
            PMTH=app.Robot.fkine(q);
            X=[transl(PMTH);flip(tr2rpy(PMTH,'zyx')')];
            app.X.Text =string(X(1));
            app.Y.Text=string(X(2));
            app.Z.Text=string(X(3));
            app.phi.Text=string(X(4));
            app.theta.Text=string(X(5));
            app.psi.Text=string(X(6));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            l0 = 350; l1 = 150; l2 = 250+75; l3 = 450;l4 = 80;
            ws= [-1000 1000 -1000 1000 0 1500];
            plot_options = {'workspace',ws,'scale',0.5,'view',[-100 30], 'tilesize',2,  'ortho', 'lightpos',[2 2 10] };
            L(1) = Link('revolute'  ,'alpha',     0,'a',   0, 'd',  l0,'offset',   0, 'qlim',        [-8*pi/9 8*pi/9],   'modified');
            L(2) = Link('revolute'  ,'alpha',  pi/2,'a',  l1, 'd',   0,'offset',pi/2, 'qlim',       [-7*pi/36 5*pi/6],   'modified');
            L(3) = Link('revolute'  ,'alpha',     0,'a',  l2, 'd',   0,'offset',   0, 'qlim',   [-11*pi/18 103*pi/90],   'modified');
            L(4) = Link('revolute'  ,'alpha',  pi/2,'a',   0, 'd',  l3,'offset',   0, 'qlim',    [-19*pi/18 19*pi/18],   'modified');
            L(5) = Link('revolute'  ,'alpha', -pi/2,'a',   0, 'd',   0,'offset',   0, 'qlim',        [-2*pi/3 2*pi/3],   'modified');
            L(6) = Link('revolute'  ,'alpha',  pi/2,'a',   0, 'd',   0,'offset',   0, 'qlim',            [-2*pi 2*pi],   'modified');
           
            app.Robot = SerialLink(L,'name','Robot','plotopt',plot_options);
            app.Robot.tool = [1,0,0,0;
                              0,1,0,0;
                              0,0,1,l4;
                              0,0,0,1];
            app.UIFigure.HandleVisibility = 'callback';
        end

        % Value changed function: J1Slider
        function J1SliderValueChanged(app, event)
            value = app.J1Slider.Value;
            app.EditField.Value=value;
            draw(app);
        end

        % Value changed function: J2Slider
        function J2SliderValueChanged(app, event)
            value = app.J2Slider.Value;
             app.EditField_2.Value=value;
             draw(app);
        end

        % Value changed function: J3Slider
        function J3SliderValueChanged(app, event)
            value = app.J3Slider.Value;
             app.EditField_3.Value=value;
             draw(app);
        end

        % Value changed function: J4Slider
        function J4SliderValueChanged(app, event)
            value = app.J4Slider.Value;
             app.EditField_4.Value=value;
             draw(app);
        end

        % Value changed function: J5Slider
        function J5SliderValueChanged(app, event)
            value = app.J5Slider.Value;
             app.EditField_5.Value=value;
             draw(app);
        end

        % Value changed function: J6Slider
        function J6SliderValueChanged(app, event)
            value = app.J6Slider.Value;
             app.EditField_6.Value=value;
             draw(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
             app.J1Slider.Value=value;
             draw(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
              app.J2Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
              app.J3Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
              app.J4Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
              app.J5Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
              app.J6Slider.Value=value;
              draw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FANUC LRMate 200iB RJ3iB')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.J1SliderLabel = uilabel(app.UIFigure);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.UIFigure);
            app.J1Slider.Limits = [-2.79252680319093 2.79252680319093];
            app.J1Slider.ValueChangedFcn = createCallbackFcn(app, @J1SliderValueChanged, true);
            app.J2SliderLabel = uilabel(app.UIFigure);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.UIFigure);
            app.J2Slider.Limits = [-0.610865238198015 2.61799387799149];
            app.J2Slider.ValueChangedFcn = createCallbackFcn(app, @J2SliderValueChanged, true);
            app.J3SliderLabel = uilabel(app.UIFigure);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.UIFigure);
            app.J3Slider.Limits = [-1.91986217719376 3.59537825910832];
            app.J3Slider.ValueChangedFcn = createCallbackFcn(app, @J3SliderValueChanged, true);
            app.J4SliderLabel = uilabel(app.UIFigure);
            app.J4SliderLabel.Text = 'J4';
            app.J4Slider = uislider(app.UIFigure);
            app.J4Slider.Limits = [-3.31612557878923 3.31612557878923];
            app.J4Slider.ValueChangedFcn = createCallbackFcn(app, @J4SliderValueChanged, true);
            app.J5SliderLabel = uilabel(app.UIFigure);
            app.J5SliderLabel.Text = 'J5';
            app.J5Slider = uislider(app.UIFigure);
            app.J5Slider.Limits = [-2.0943951023932 2.0943951023932];
            app.J5Slider.ValueChangedFcn = createCallbackFcn(app, @J5SliderValueChanged, true);
            app.J6SliderLabel = uilabel(app.UIFigure);
            app.J6SliderLabel.Text = 'J6';
            app.J6Slider = uislider(app.UIFigure);
            app.J6Slider.Limits = [-6.28318530717959 6.28318530717959];
            app.J6Slider.ValueChangedFcn = createCallbackFcn(app, @J6SliderValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.CinemticadirectaLabel = uilabel(app.UIFigure);
            app.CinemticadirectaLabel.Text = 'Cinemática directa';
            app.PosicindelefectorfinalLabel = uilabel(app.UIFigure);
            app.PosicindelefectorfinalLabel.Text = 'Posición del efector final';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.X = uilabel(app.UIFigure);
            app.X.Text = '';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.Y = uilabel(app.UIFigure);
            app.Y.Text = '';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.Z = uilabel(app.UIFigure);
            app.Z.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'φ:';
            app.phi = uilabel(app.UIFigure);
            app.phi.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'θ:';
            app.theta = uilabel(app.UIFigure);
            app.theta.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'ψ:';
            app.psi = uilabel(app.UIFigure);
            app.psi.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end